classdef aplikacja < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        StartButton                    matlab.ui.control.Button
        MarkaLabel                     matlab.ui.control.Label
        WyczytanypojazdjestmarkiLabel  matlab.ui.control.Label
        WczytajObrazButton             matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Automatyczne ustawienie folderu aplikacji jako bieżącego katalogu roboczego
            appFolder = fileparts(mfilename('fullpath'));
            cd(appFolder);

            % Dodanie folderu aplikacji i jego podfolderów do ścieżki
            addpath(genpath(appFolder));

            outputFolder = 'loaded'; % Nazwa folderu
            if ~exist(outputFolder, 'dir')
                mkdir(outputFolder);
            else
                % Wyczyść folder 'loaded'
                files = dir(fullfile(outputFolder, '*')); % Lista plików w folderze
                for i = 1:length(files)
                    if ~files(i).isdir
                        delete(fullfile(outputFolder, files(i).name)); % Usuń plik
                    end
                end
            end
        end

        % Button pushed function: WczytajObrazButton
        function WczytajObrazButtonPushed(app, event)
             % Wybierz plik graficzny
            [file, path] = uigetfile({'*.jpg;*.png', 'Pliki graficzne (*.jpg, *.png)'; ...
                                      '*.*', 'Wszystkie pliki (*.*)'}, 'Wybierz obraz');
            if isequal(file, 0)
                % Jeśli użytkownik anulował, wyjdź z funkcji
                return;
            end

            % Pełna ścieżka do wybranego pliku
            fullPath = fullfile(path, file);
            
            try
                img = imread(fullPath);
                
                % Wyświetl obraz na osi
                app.Image.ImageSource = fullPath; % Ścieżka do pliku
                
                % Upewnij się, że folder 'loaded' istnieje
                outputFolder = 'loaded'; % Nazwa folderu
                % Wyczyść folder 'loaded'
                files = dir(fullfile(outputFolder, '*')); % Lista plików w folderze
                for i = 1:length(files)
                    if ~files(i).isdir
                        delete(fullfile(outputFolder, files(i).name)); % Usuń plik
                    end
                end
                        
                % Zapisz obraz do folderu 'loaded'
                [~, name, ext] = fileparts(file); % Pobierz nazwę i rozszerzenie pliku
                outputFilePath = fullfile(outputFolder, [name, ext]); % Ścieżka docelowa
                imwrite(img, outputFilePath);
                
            catch ME
                % Obsługa błędu
                uialert(app.UIFigure, 'Nie udało się wczytać obrazu.', 'Błąd');
                disp(ME.message); % Wyświetl komunikat błędu w Command Window
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            try
                % Ścieżka do pliku main.m
                mainFile = 'src/main.m';
                
                % Sprawdź, czy plik istnieje
                if exist(mainFile, 'file') == 2
                    % Pobierz informacje o plikach w folderze
                    files = dir(fullfile('loaded', '*.*'));
                    files = files(~[files.isdir]);
                    if isempty(files)
                        uialert(app.UIFigure, 'Wczytaj obraz.', 'Błąd');
                    else
                        run(mainFile);
                    end
                else
                    uialert(app.UIFigure, 'Plik nie istnieje.', 'Błąd');
                end
            catch ME
                uialert(app.UIFigure, ['Wystąpił błąd: ' ME.message], 'Błąd');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.WczytajObrazButton = uibutton(app.LeftPanel, 'push');
            app.WczytajObrazButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajObrazButtonPushed, true);
            app.WczytajObrazButton.Text = 'Wczytaj Obraz';
            app.WyczytanypojazdjestmarkiLabel = uilabel(app.LeftPanel);
            app.WyczytanypojazdjestmarkiLabel.Text = 'Wyczytany pojazd jest marki:';
            app.MarkaLabel = uilabel(app.LeftPanel);
            app.MarkaLabel.Text = '(nie urochomiono programu)';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aplikacja

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end